classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ResultsrunanalysisLabel     matlab.ui.control.Label
        RunAnalysisButton           matlab.ui.control.Button
        CropfractionEditField       matlab.ui.control.NumericEditField
        CropfractionEditFieldLabel  matlab.ui.control.Label
        ScaleumEditField            matlab.ui.control.NumericEditField
        ScaleumEditFieldLabel       matlab.ui.control.Label
        ScalepxEditField            matlab.ui.control.NumericEditField
        ScalepxEditFieldLabel       matlab.ui.control.Label
        LoadImageButton             matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Internal state (not UI components)
    properties (Access = private)
        ImageRaw                % Loaded raw image matrix
        ImagePath = ''          % Full path to loaded image
        ImageFilename = ''      % Filename only
        State                   % AnalysisState after a run
    end

    methods (Access = private)

        % Add project folders (pipeline/, utils/, compat/) to the path so
        % the analysis functions resolve regardless of launch directory.
        function ensureProjectPath(app)
            f = which(class(app));
            if isempty(f); f = mfilename('fullpath'); end
            root = fileparts(f);
            if ~isempty(root); addpath(genpath(root)); end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that runs after component creation
        function startupFcn(app)
            app.ensureProjectPath();
            app.ResultsrunanalysisLabel.Text = ['Load an image, set the scale bar ' ...
                '(px + um) and crop fraction, then click Run Analysis.'];
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Let the user pick an image file
            [file, path] = uigetfile( ...
                {'*.png;*.jpg;*.jpeg;*.tif;*.tiff;*.gif;*.bmp', 'Image Files'}, ...
                'Select a microstructure image');
            if isequal(file, 0)
                return;   % user cancelled
            end

            % Read and display it in the axes
            img = imread(fullfile(path, file));
            app.ImageRaw      = img;
            app.ImagePath     = fullfile(path, file);
            app.ImageFilename = file;

            try
                imshow(img, 'Parent', app.UIAxes);
            catch
                image(app.UIAxes, img);
                app.UIAxes.DataAspectRatio = [1 1 1];
                app.UIAxes.YDir = 'reverse';
            end
            title(app.UIAxes, 'Microstructure Image');
            app.ResultsrunanalysisLabel.Text = sprintf('Loaded: %s', file);
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            if isempty(app.ImageRaw)
                uialert(app.UIFigure, 'Load an image first.', 'No image');
                return;
            end
            app.ensureProjectPath();

            try
                scale_px = app.ScalepxEditField.Value;
                scale_um = app.ScaleumEditField.Value;
                cropf    = app.CropfractionEditField.Value;
                if scale_px <= 0 || scale_um <= 0
                    error('Scale (px) and Scale (um) must be positive.');
                end
                if cropf <= 0 || cropf > 1
                    error('Crop fraction must be in (0, 1].');
                end

                state = AnalysisState();
                state.image_raw = app.ImageRaw;
                if size(state.image_raw, 3) == 3
                    state.image_raw = rgb2gray(state.image_raw);
                end
                state.image_filename = app.ImageFilename;

                if cropf < 1.0
                    h = size(state.image_raw, 1);
                    state.image_raw = state.image_raw(1:round(h * cropf), :);
                end

                state = calibrate_scale(state, scale_px, scale_um);
                state = preprocess_image(state);
                state = segment_grains(state);
                state = analyze_morphology(state);
                state = generate_publication_figure(state);

                figImg = state.figure_image;
                if isfield(state.figure_handles, 'fig') && ...
                        ~isempty(state.figure_handles.fig) && ...
                        isgraphics(state.figure_handles.fig)
                    close(state.figure_handles.fig);
                end
                app.State = state;

                if ~isempty(figImg)
                    try
                        imshow(figImg, 'Parent', app.UIAxes);
                    catch
                        image(app.UIAxes, figImg);
                        app.UIAxes.DataAspectRatio = [1 1 1];
                        app.UIAxes.YDir = 'reverse';
                    end
                    title(app.UIAxes, 'Analysis (4-panel)');
                end

                m = state.morphology_stats;
                app.ResultsrunanalysisLabel.Text = sprintf([ ...
                    'Calibration: %.4f um/px\n' ...
                    'Grain count: %d\n' ...
                    'D10/D50/D90: %.2f / %.2f / %.2f um\n' ...
                    'Eccentricity: %.3f +/- %.3f\n' ...
                    'Orientation: %.1f +/- %.1f deg\n' ...
                    'Mean area: %.2f um^2'], ...
                    state.calibration_factor, m.grain_count, ...
                    m.D10, m.D50, m.D90, ...
                    m.mean_eccentricity, m.std_eccentricity, ...
                    m.mean_orientation, m.std_orientation, m.mean_area_um2);

            catch ME
                app.ResultsrunanalysisLabel.Text = ['ERROR: ' ME.message];
                uialert(app.UIFigure, ME.message, 'Analysis failed');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Microstructure Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ScalepxEditFieldLabel = uilabel(app.UIFigure);
            app.ScalepxEditFieldLabel.Text = 'Scale (px)';
            app.ScalepxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScalepxEditField.Value = 260;
            app.ScaleumEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleumEditFieldLabel.Text = 'Scale (um)';
            app.ScaleumEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleumEditField.Value = 300;
            app.CropfractionEditFieldLabel = uilabel(app.UIFigure);
            app.CropfractionEditFieldLabel.Text = 'Crop fraction';
            app.CropfractionEditField = uieditfield(app.UIFigure, 'numeric');
            app.CropfractionEditField.Value = 0.88;
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.ResultsrunanalysisLabel = uilabel(app.UIFigure);
            app.ResultsrunanalysisLabel.Text = 'Results: (run analysis)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end